classdef Final_Practice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        DetailedResultsTabGroup         matlab.ui.container.TabGroup
        TemperatureDistributionTab      matlab.ui.container.Tab
        TemperatureDist                 matlab.ui.control.UIAxes
        CalendarAgingTab                matlab.ui.container.Tab
        CalAgingAxes                    matlab.ui.control.UIAxes
        CycleAgingHighTempTab           matlab.ui.container.Tab
        CycHighTempAxes                 matlab.ui.control.UIAxes
        CycleAgingLowTempTab            matlab.ui.container.Tab
        CycLowTempAxes                  matlab.ui.control.UIAxes
        CycleAgingLowTempHighSOCTab     matlab.ui.container.Tab
        CycLowTempHighSOCAxes           matlab.ui.control.UIAxes
        Switch                          matlab.ui.control.Switch
        SimulationTimeEditField         matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        CellParametersPanel             matlab.ui.container.Panel
        PowerWEditField                 matlab.ui.control.NumericEditField
        PowerWEditFieldLabel            matlab.ui.control.Label
        EnergyWhEditField               matlab.ui.control.NumericEditField
        EnergyWhEditFieldLabel          matlab.ui.control.Label
        DriveCyclePanel                 matlab.ui.container.Panel
        EditField3                      matlab.ui.control.EditField
        BrowseFileButton                matlab.ui.control.Button
        DriveCycleDropDown              matlab.ui.control.DropDown
        DriveCycleDropDownLabel         matlab.ui.control.Label
        CapacityFadeEditField           matlab.ui.control.NumericEditField
        CapacityFadeEditFieldLabel      matlab.ui.control.Label
        TemperatureDistributionPanel    matlab.ui.container.Panel
        CityDropDown                    matlab.ui.control.DropDown
        CityDropDownLabel               matlab.ui.control.Label
        EditField3_2                    matlab.ui.control.EditField
        BrowseFileButton_2              matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        DetailedResultsCheckBox         matlab.ui.control.CheckBox
        CalculateButton                 matlab.ui.control.Button
        CellButtonGroup                 matlab.ui.container.ButtonGroup
        CustomButton_3                  matlab.ui.control.RadioButton
        NMCButton                       matlab.ui.control.RadioButton
        LFPButton                       matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        VehicleTab                      matlab.ui.container.Tab
        VehicleButtonGroup              matlab.ui.container.ButtonGroup
        CustomVehicleButton             matlab.ui.control.RadioButton
        Ather450XButton                 matlab.ui.control.RadioButton
        TataNexonEVPrimeButton          matlab.ui.control.RadioButton
        CustomVehiclePanel              matlab.ui.container.Panel
        MaximumSpeedkmphEditField_3     matlab.ui.control.NumericEditField
        MaximumSpeedkmphEditField_3Label  matlab.ui.control.Label
        DischargeVoltageVEditField_3Label  matlab.ui.control.Label
        DischargeVoltageVEditField_3    matlab.ui.control.NumericEditField
        EnergykWhEditField_3            matlab.ui.control.NumericEditField
        EnergykWhEditField_3Label       matlab.ui.control.Label
        MotorPowerkWEditField_3         matlab.ui.control.NumericEditField
        MotorPowerkWEditField_3Label    matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Ather450XPanel                  matlab.ui.container.Panel
        DischargeVoltageVEditField_2    matlab.ui.control.NumericEditField
        DischargeVoltageVEditField_2Label  matlab.ui.control.Label
        EnergykWhEditField_2            matlab.ui.control.NumericEditField
        EnergykWhEditField_2Label       matlab.ui.control.Label
        MotorPowerkWEditField_2         matlab.ui.control.NumericEditField
        MotorPowerkWEditField_2Label    matlab.ui.control.Label
        MaximumSpeedkmphEditField_2     matlab.ui.control.NumericEditField
        MaximumSpeedkmphEditField_2Label  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        TataNexonEVPrimePanel           matlab.ui.container.Panel
        MaximumSpeedkmphEditField       matlab.ui.control.NumericEditField
        MaximumSpeedkmphEditFieldLabel  matlab.ui.control.Label
        DischargeVoltageVEditField      matlab.ui.control.NumericEditField
        DischargeVoltageVEditFieldLabel  matlab.ui.control.Label
        MotorPowerkWEditField           matlab.ui.control.NumericEditField
        MotorPowerkWEditFieldLabel      matlab.ui.control.Label
        EnergykWhEditField              matlab.ui.control.NumericEditField
        EnergykWhEditFieldLabel         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ModelParametersTab              matlab.ui.container.Tab
        ModelButtonGroup                matlab.ui.container.ButtonGroup
        CustomModelButton               matlab.ui.control.RadioButton
        SchimpeetalButton               matlab.ui.control.RadioButton
        AnodeOpenCircuitPotentialPanel  matlab.ui.container.Panel
        StoichiometryxSOC100EditField   matlab.ui.control.NumericEditField
        StoichiometryxSOC100EditFieldLabel  matlab.ui.control.Label
        StoichiometryxSOC0EditField     matlab.ui.control.NumericEditField
        StoichiometryxSOC0EditFieldLabel  matlab.ui.control.Label
        CycleAgingatLowTemperatureandHighSOCPanel  matlab.ui.container.Panel
        SOC_RefEditField                matlab.ui.control.NumericEditField
        SOC_RefLabel                    matlab.ui.control.Label
        beta_LowTHighSOChEditField      matlab.ui.control.NumericEditField
        beta_LowTHighSOChEditFieldLabel  matlab.ui.control.Label
        ActivationEnergyE_aCycLowTHighSOCJmolEditField  matlab.ui.control.NumericEditField
        ActivationEnergyE_aCycLowTHighSOCJmolLabel  matlab.ui.control.Label
        ReferenceStressFactork_CycLowTHighSOCRefh1EditField  matlab.ui.control.NumericEditField
        ReferenceStressFactork_CycLowTHighSOCRefh1Label  matlab.ui.control.Label
        CycleAgingatLowTemperaturePanel  matlab.ui.container.Panel
        beta_LowThEditField             matlab.ui.control.NumericEditField
        beta_LowThEditFieldLabel        matlab.ui.control.Label
        ReferenceCurrentI_ChRefAEditField  matlab.ui.control.NumericEditField
        ReferenceCurrentI_ChRefALabel   matlab.ui.control.Label
        NominalCellCapacityC_0AhEditField  matlab.ui.control.NumericEditField
        NominalCellCapacityC_0AhLabel   matlab.ui.control.Label
        ActivationEnergyE_aCycLowTJmolEditField  matlab.ui.control.NumericEditField
        ActivationEnergyE_aCycLowTJmolLabel  matlab.ui.control.Label
        ReferenceStressFactork_CycLowTRefAh05EditField  matlab.ui.control.NumericEditField
        ReferenceStressFactork_CycLowTRefAh05Label  matlab.ui.control.Label
        CycleAgingatHighTemperaturePanel  matlab.ui.container.Panel
        ActivationEnergyE_aCycHighTJmolEditField  matlab.ui.control.NumericEditField
        ActivationEnergyE_aCycHighTJmolLabel  matlab.ui.control.Label
        ReferenceStressFactork_CycHighTRefAh05EditField  matlab.ui.control.NumericEditField
        ReferenceStressFactork_CycHighTRefAh05Label  matlab.ui.control.Label
        CalendarAgingPanel              matlab.ui.container.Panel
        FittingParameterk_0EditField    matlab.ui.control.NumericEditField
        FittingParameterk_0Label        matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaLabel                      matlab.ui.control.Label
        ReferencePotentialU_aRefVEditField  matlab.ui.control.NumericEditField
        ReferencePotentialU_aRefVLabel  matlab.ui.control.Label
        ActivationEnergyE_aCalJmolEditField  matlab.ui.control.NumericEditField
        ActivationEnergyE_aCalJmolLabel  matlab.ui.control.Label
        ReferenceStressFactork_CalRefh05EditField  matlab.ui.control.NumericEditField
        ReferenceStressFactork_CalRefh05Label  matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        Step9EditField                  matlab.ui.control.EditField
        Step9EditFieldLabel             matlab.ui.control.Label
        Step8EditField                  matlab.ui.control.EditField
        Step8EditFieldLabel             matlab.ui.control.Label
        Step7EditField                  matlab.ui.control.EditField
        Step7EditFieldLabel             matlab.ui.control.Label
        Step6EditField                  matlab.ui.control.EditField
        Step6EditFieldLabel             matlab.ui.control.Label
        Step5EditField                  matlab.ui.control.EditField
        Step5EditFieldLabel             matlab.ui.control.Label
        Step4EditField                  matlab.ui.control.EditField
        Step4EditFieldLabel             matlab.ui.control.Label
        Step3EditField                  matlab.ui.control.EditField
        Step3EditFieldLabel             matlab.ui.control.Label
        Step2EditField                  matlab.ui.control.EditField
        Step2EditFieldLabel             matlab.ui.control.Label
        Step1EditField                  matlab.ui.control.EditField
        Step1EditFieldLabel             matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
    end

    properties (Access = private) 
        Time_array % Time in days
        Time_array_Seconds % Time in seconds used while exporting data
        Q_L_cal % Capacity loss due to calendar aging
        Q_L_cyc_h_T % Capacity loss due to cycle aging at high temperature
        Q_L_cyc_l_T % Capacity loss due to cycle aging at low temperature
        Q_L_cyc_LT_HSOC % Capacity loss due to cycle aging at low temperature and high SOC
        Q_L_Total % Total capacity loss
        CustomDriveCycle % Custom drive cycle data
        CustomTemperature % Custom temperature data
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %% Inputs
            % This call back is related to 'calculate' button. Some of the variables like vehicle power are defined again here 
            % so that only when user clicks calculate, the present selected values will be used for calcualtions. 
            Analysis_Time = app.SimulationTimeEditField.Value; % Time for which analysis is being conducted
            switch app.Switch.Value
                case 'Days'
                    Time_of_Study = Analysis_Time*24; % hours (If time was given in days, convert into hours)
                otherwise
                    Time_of_Study = Analysis_Time*365*24; % hours (If time was given in years, convert into days and then convert into hours)
            end
            Storing_Time = 2*60*60; % seconds - After every 'Storing_Time' interval, values are stored for plotting
            % The following if condition selects power,energy, discharge
            % voltage and speed of the vehicle according to the radio button
            % selected. This can also be done by declaring the
            % aforementioned variables as properties.
            selectedButton = app.VehicleButtonGroup.SelectedObject;
            if selectedButton == app.TataNexonEVPrimeButton % Tata Nexon scenario. Note: Refer to variable names in design view for better understanding
                Vehicle_Power = app.MotorPowerkWEditField.Value;
                Vehicle_Energy = app.EnergykWhEditField.Value;
                Vehicle_Discharge_Voltage = app.DischargeVoltageVEditField.Value;
                Vehicle_Maximum_Speed = app.MaximumSpeedkmphEditField.Value;
            elseif selectedButton == app.Ather450XButton % Ather 450X scenario
                Vehicle_Power = app.MotorPowerkWEditField_2.Value;
                Vehicle_Energy = app.EnergykWhEditField_2.Value;
                Vehicle_Discharge_Voltage = app.DischargeVoltageVEditField_2.Value;
                Vehicle_Maximum_Speed = app.MaximumSpeedkmphEditField_2.Value;
            else 
                Vehicle_Power = app.MotorPowerkWEditField_3.Value; % Custom vehicle scenario
                Vehicle_Energy = app.EnergykWhEditField_3.Value;
                Vehicle_Discharge_Voltage = app.DischargeVoltageVEditField_3.Value;
                Vehicle_Maximum_Speed = app.MaximumSpeedkmphEditField_3.Value;
            end
            Vehicle_Max_Discharge_Current = Vehicle_Power*1000/Vehicle_Discharge_Voltage; % Maximum discharge current of vehicle in A (1000 is multiplied to convert kW inot W)
            Cell_Energy = app.EnergyWhEditField.Value; % Energy of each cell 
            Cell_Power = app.PowerWEditField.Value; % Power of each cell
            PE_Cell = Cell_Power/Cell_Energy; % P/E ratio of cell
            PE_Vehicle = Vehicle_Power/Vehicle_Energy; % P/E ratio of vehicle
            % The following if condition gives a warning window and matches power or energy depending
            % on P/E ratios of cell and vehicle.
            if PE_Vehicle > PE_Cell
                message = {'P/E of vehicle is more than P/E of cell, matching power','Vehicle battery now has more energy than what is required'}; % Message to be declared in warning window
                uialert(app.UIFigure,message,'Warning','Icon','warning'); % Warning window (Icon has been set to usual warning image)
                n_cells_series = Vehicle_Discharge_Voltage/3.2; % Number of cells in series in the battery pack calculated by dividing voltages of vehicle battery and cell
                n_cells_parallel = (Vehicle_Power*1000/Vehicle_Discharge_Voltage)/(Cell_Power/3.2); % Number of cells in parallel calculated by dividing discharge current of vehicle battery and cell. 
                factor = 1;
                % Note that power of vehicle is given in kW whereas power
                % of cell is given in W.
            elseif PE_Vehicle < PE_Cell
                message = {'P/E of vehicle is less than P/E of cell.','Matching Energy','Vehicle battery now has more power than what is required'}; % Message to be declared in warning window
                uialert(app.UIFigure,message,'Warning','Icon','warning'); % Warning window (Icon has been set to usual warning image)
                n_cells_series = Vehicle_Discharge_Voltage/3.2; % Number of cells in series in the battery pack calculated by dividing voltages of vehicle battery and cell
                n_cells_parallel = (Vehicle_Energy*1000/Vehicle_Discharge_Voltage)/(Cell_Energy/3.2) ;% Number of cells in parallel calculated by dividing capacity of vehicle battery and cell. 
                factor = Vehicle_Power/(n_cells_series*n_cells_parallel*Cell_Power);
                % Note that energy of vehicle is given in kWh whereas
                % energy of cell is given in Wh.
            else 
                n_cells_series = Vehicle_Discharge_Voltage/3.2;
                n_cells_parallel = (Vehicle_Energy*1000/Vehicle_Discharge_Voltage)/(Cell_Energy/3.2);
                factor = 1;
            end % If P/E of cell and vehicle are equal, matching energy or power is same (Ideal scenario). Hence warning message is not written for this case
            pause(1); % Letting the user read the warning
            % Progress window
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Starting the application'); % 'd' just represents name of progress window.
            pause(1) % Letting the user read title of progress window
            d.Value = .33; % Increase the progress bar to 33 %
            d.Message = 'Loading the data'; % Message to be displayed
            pause(1) % Letting the user read the message

            %% Temperature Data
            switch app.CityDropDown.Value % Mothly maximum and minimum, hourly temperatures are preloaded
                case 'Chennai'
                    Monthly_Minimum = [21.5 22.4 24.5 26.9 28.2 27.9 26.8 26.2 25.8 25 23.5 22.2]; % C 
                    Monthly_Maximum = [29.7 31.2 33.1 34.7 37.3 37.2 35.7 34.9 34.3 32.6 30.3 29.4]; % C
                    Per_Hour_Temp = [25 24 24 24 23 22 22 23 23 25 27 28 29 29 29 29 29 28 27 27 27 26 26 26]; % C 
                case 'Mumbai'
                    Monthly_Minimum = [19.4 20.4 23 25.3 27.3 26.6 25.5 25.2 25.1 25.1 23.6 21.2]; % C 
                    Monthly_Maximum = [30.2 30.3 31.7 32.9 34 32.2 29.9 29.9 30.6 33.1 33.8 32.2]; % C
                    Per_Hour_Temp = [25 25 24 24 24 24 23 24 24 26 27 29 31 30 30 30 29 28 27 27 26 26 25 25]; % C 
                case 'Delhi'
                    Monthly_Minimum = [8.7 12.1 16.8 22 25.9 27 26.1 25.5 24.1 20.3 15.1 9.9]; % C 
                    Monthly_Maximum = [19 24.4 31 37 40.7 39.8 35.1 33.9 34 33.4 28 22.5]; % C
                    Per_Hour_Temp = [17 16 16 15 15 15 15 15 16 19 22 24 26 27 28 29 28 27 25 23 22 21 19 18]; % C 
                case 'Leh'
                    Monthly_Minimum = [-13.1 -9.2 -3.7 1.6 6.2 11.3 15.9 15.1 9.2 0.3 -7.2 -11.7]; % C 
                    Monthly_Maximum = [1.5 4.2 9.7 15.3 20 24.4 28.5 28.1 23.4 16.7 10.6 4.5]; % C
                    %Per_Hour_Temp = [-12 -12 -12 -12 -12 -13 -13 -12 -12 -10 -8 -7 -6 -5 -6 -6 -6 -7 -8 -8 -8 -8 -8 -8]; % C 
                    Per_Hour_Temp = [-1 -3 -4 -5 -6 -6 -7 -6 -5 -3 -2 -1 0 1 2 3 2 1 0 0 -1 -2 -3 -4]; % C 
                otherwise
                    T = readmatrix(app.CustomTemperature);
                    Monthly_Minimum = T(1,:);
                    Monthly_Maximum = T(2,:);
                    Per_Hour_Temp = T(3,:);
            end
            Daily_Temp_Min = zeros(1,365);% Preallocation for speed
            Daily_Temp_Max = zeros(1,365);% Preallocation for speed 
            Mid_Days = [16 45 75 105 136 166 197 228 258 289 319 350];% Mid days of each month for which IMD data is assigned 
            Monthly_Maximum = Monthly_Maximum + 273.15;% Converting to Kelvin 
            Monthly_Minimum = Monthly_Minimum + 273.15;% Converting to Kelvin 
            Per_Hour_Temp = Per_Hour_Temp + 273.15;% Converting to Kelvin 
            Mid_Month = 1; % Index to track which month we are calculating
            % Linear interpolation to get daily minimum and maximum
            % Following for loop starts from 16 th day (From January 1 st) and goes till December 16
            for i = 16:350
                if ((i > Mid_Days(Mid_Month)) && (Mid_Month < 12))
                    Mid_Month = Mid_Month + 1;
                end
                if (i == Mid_Days(Mid_Month))
                    Daily_Temp_Min(i) = Monthly_Minimum(Mid_Month); % K
                    Daily_Temp_Max(i) = Monthly_Maximum(Mid_Month); % K
                else
                    Lower_Day = Mid_Days(Mid_Month-1);
                    Upper_Day = Mid_Days(Mid_Month);
                    Daily_Temp_Max(i) = Daily_Temp(Monthly_Maximum(Mid_Month-1),Monthly_Maximum(Mid_Month),Lower_Day,Upper_Day,i);
                    Daily_Temp_Min(i) = Daily_Temp(Monthly_Minimum(Mid_Month-1),Monthly_Minimum(Mid_Month),Lower_Day,Upper_Day,i);
                end
            end
            % Following for loop is for January 1 st to January 15 th and December 17 th to December 31 st
            % These values are calculated seperately because interpolation needs to be
            % circular i.e., while calculating January 1st till January 15 th, lower
            % limits should be that of December and for December 17 th to December 31
            % st, upper limit should be January 16 th 
            for i = 2:31
                Lower_Day = 1;
                Upper_Day = 32;
                if i < 17
                    Daily_Temp_Max(349 + i) = Daily_Temp(Monthly_Maximum(12),Monthly_Maximum(1),Lower_Day,Upper_Day,i);
                    Daily_Temp_Min(349 + i) = Daily_Temp(Monthly_Minimum(12),Monthly_Minimum(1),Lower_Day,Upper_Day,i);
                else
                    Daily_Temp_Max(i - 16) = Daily_Temp(Monthly_Maximum(12),Monthly_Maximum(1),Lower_Day,Upper_Day,i);
                    Daily_Temp_Min(i - 16) = Daily_Temp(Monthly_Minimum(12),Monthly_Minimum(1),Lower_Day,Upper_Day,i);
                end
            end
            % Rest Calculations
            Initial_SOC = 1; % Assuming battery was fully charged initially
            Delta_Time = 1; % seconds - Calculations are done after every 'Delta_Time' seconds
            SOC_Recalibration_Time = 1*60; % seconds - SOC is recalibrated after this interval everytime throughout the time of study
            % Cell parameters are taken from the Schimpe research paper. Commercial 26650-format lithium iron phosphate cells (Sony US26650FTC1) were used for the study.
            Cell_Nominal_Capacity = 3000; % mAh
            Cell_Charge_Current = 2.85; % A
            Per_Hour_Max = max(Per_Hour_Temp);
            Per_Hour_Min = min(Per_Hour_Temp);
            Per_Hour_Diff = Per_Hour_Max - Per_Hour_Min;
            % Following for loop calculates normalization factor to estimate
            % temperature at any time of the day given that day's max and min based on
            % hourly data presented earlier i.e., hourly temperature is normailsed in
            % such a way that hourly min equals to zero and hourly max equals to one
            Norm_Factor = zeros(1,24); % Preallocating for speed
            for i = 1:24
                Norm_Factor(i) = (Per_Hour_Temp(i) - Per_Hour_Min)/(Per_Hour_Diff);
            end
            %% Model parameters are taken from Schimpe et al
            k_cal_ref = app.ReferenceStressFactork_CalRefh05EditField.Value; % h^(-0.5)
            E_a_cal = app.ActivationEnergyE_aCalJmolEditField.Value; % J/mol
            R_g = 8.314; % J/(mol K)
            T_ref = 298.15; % K
            U_a_ref = app.ReferencePotentialU_aRefVEditField.Value; % V
            alpha = app.alphaEditField.Value; 
            k0 = app.FittingParameterk_0EditField.Value; % no unit
            F = 96385; % C/mol
            k_cyc_h_T_ref = app.ReferenceStressFactork_CycHighTRefAh05EditField.Value; % (Ah)^(-0.5);
            E_a_cyc_h_T = app.ActivationEnergyE_aCycHighTJmolEditField.Value; % J/mol
            k_cyc_l_T_ref = app.ReferenceStressFactork_CycLowTRefAh05EditField.Value; % (Ah)^(-0.5);
            E_a_cyc_l_T = app.ActivationEnergyE_aCycLowTJmolEditField.Value; % J/mol
            C0 = app.NominalCellCapacityC_0AhEditField.Value; % Ah
            I_ch_ref = app.ReferenceCurrentI_ChRefAEditField.Value; % A
            beta_l_T = app.beta_LowThEditField.Value; % h
            k_cyc_l_T_h_SOC_ref = app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Value; % (Ah)^(-1);
            E_a_cyc_l_T_h_SOC = app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Value; % J/mol
            beta_l_T_h_SOC = app.beta_LowTHighSOChEditField.Value; % h
            SOC_ref = app.SOC_RefEditField.Value*0.01;
            % Degree of lithiation parameters
            x_SOC_0 = app.StoichiometryxSOC0EditField.Value; 
            x_SOC_100 = app.StoichiometryxSOC100EditField.Value;
            d.Value = .67; % Progress bar value
            d.Message = 'Processing the data'; % Progress bar message
            
            %% Capacity loss calculation with periodic SOC recalibration
            Present_Charge = Initial_SOC*Cell_Nominal_Capacity*3600/1000; % C - Charge in the cell before conducting tests
            Initial_Total_Charge = Cell_Nominal_Capacity*3600/1000; % C - Total charge that can be stored in a new cell
            Present_Total_Charge = Cell_Nominal_Capacity*3600/1000; % C - Total charge that can be stored in the cell at present
            Number_of_Iterations = Time_of_Study*60*60; % Number of seconds in time of study
            % Calculations for first iteration 
            Storing_Index = 1; % Index for storing variables that will be plotted later on
            numDays = 1; % Number of days elaspsed since start of the study
            SOC_curr = Present_Charge/Present_Total_Charge; % Current SOC
            T_curr = Daily_Temp_Min(1) + (Norm_Factor(1)*(Daily_Temp_Max(1) - Daily_Temp_Min(1))); % Current temperature at first iteration (first second of Time_of_Study)
            % Assuming same discharge cycle everyday twice for 365 days and charging once till SOC reached 100% 
            % Number of cells required are 100 in series to provide 320 V of discharge
            % voltage (3.2 V for each cell) and 30 branches in parallel to provide 300
            % A discharge current (10 A from each cell) 
            Cell_Discharge_Throughput = 0/n_cells_parallel; % Ah 
            Cell_Charge_Throughput = (Present_Total_Charge - Present_Charge)/3600; % Ah 3600 to convert C to Ah
            Cell_Total_Charge_Throughput = Cell_Discharge_Throughput + Cell_Charge_Throughput; % Ah
            x_a_curr = Degree_of_Lithiation(SOC_curr,x_SOC_0,x_SOC_100); % Degree of lithiation
            U_a_curr = OCV_Anode(x_a_curr); % OCV of anode calculation
            % Stress factors
            k_cal_T_SOC_curr = Stress_Factor_Calendar_Aging(T_curr,U_a_curr); % Calendar aging
            k_cyc_h_T_curr = Stress_Factor_Cycle_Aging_High_Temperature(T_curr); % Cycle aging at high temperature
            k_cyc_l_T_curr = Stress_Factor_Cycle_Aging_Low_Temperature(T_curr,Cell_Charge_Current); % Cycle aging at low temperature
            k_cyc_l_T_h_SOC_curr = Stress_Factor_Cycle_Aging_Low_Temperature_High_SOC(T_curr,Cell_Charge_Current,SOC_curr); % Cycle aging at low temperature and high SOC
            % Integrands
            Cal_curr = (k_cal_T_SOC_curr)/(2*sqrt(1/3600)); % Calendar aging
            % The following three integrands are initiated to zero as charge throughput
            % is zero at the moment (first second on Time_of_Study) at that parameter
            % comes in denominator for all three. To avoid infinity values, they are
            % initiated to zero.
            Cyc_HT_curr = 0; % Cycle aging at high temperature 
            Cyc_LT_curr = 0; % Cycle aging at low temperature
            Cyc_LT_HSOC_curr = 0; % Cycle aging at low temperature and high SOC
            % Previous (First iteration capacity loss) initiated to zero
            Q_L_cal_prev = 0; % Calendar aging 
            Q_L_cyc_h_T_prev = 0; % Cycle aging at high temperature 
            Q_L_cyc_l_T_prev = 0; % Cycle aging at low temperature
            Q_L_cyc_LT_HSOC_prev = 0; % Cycle aging at low temperature and high SOC
            Present_Charge = Present_Charge - (Cell_Discharge_Throughput*3600); % C 
            SOC_curr = Present_Charge/Present_Total_Charge;
            Cal_prev = Cal_curr;
            Cyc_HT_prev = Cyc_HT_curr;
            Cyc_LT_prev = Cyc_LT_curr;
            Cyc_LT_HSOC_prev = Cyc_LT_HSOC_curr;
            nSecHour = 1*60*60; % Number of seconds in one hour
            % nSecDay = number of seconds in one day
            nSecDay = 24*60*60; % Number of seconds in a day
            % cSec(i) = cumulative number of seconds passed till i th hour from 00:00
            cSec = zeros(1,24); % Preallocating for speed
            cSec(1) = nSecHour;
            for i = 2:24
                cSec(i) = cSec(i-1) + nSecHour;
            end
            Storing_Number = Number_of_Iterations/Storing_Time; % Number of data points to be stored
            % Following variables are stored and plotted
            app.Time_array = zeros(1,Storing_Number); % Preallocating for speed
            app.Q_L_cal = zeros(1,Storing_Number); % Preallocating for speed
            app.Q_L_cyc_h_T = zeros(1,Storing_Number); % Preallocating for speed
            app.Q_L_cyc_l_T = zeros(1,Storing_Number); % Preallocating for speed
            app.Q_L_cyc_LT_HSOC = zeros(1,Storing_Number); % Preallocating for speed
            SOC_Plot = zeros(1,Storing_Number); % Preallocating for speed
            app.Q_L_Total = zeros(1,Storing_Number); % Preallocating for speed
            % Charge starts at 21:00 everyday
            Charge_Start_Time = 21*60*60; % Seconds
            Cell_Total_Charge_Throughput_prev = 0;
            switch app.DriveCycleDropDown.Value
                case 'Work Commute'
                    Drive_Cycle_Data = readmatrix('Drive_Cycle_Data.xlsx','Sheet','Work_Commute');
                case 'Swiggy'
                    Drive_Cycle_Data = readmatrix('Drive_Cycle_Data.xlsx','Sheet','Swiggy');
                case 'Ola'
                    Drive_Cycle_Data = readmatrix('Drive_Cycle_Data.xlsx','Sheet','Ola');
                otherwise
                    Drive_Cycle_data = readmatrix(app.CustomDriveCycle);
            end
            Velocity = Drive_Cycle_Data(:,2);
            Discharge_Current = current(Velocity,Vehicle_Maximum_Speed,Vehicle_Max_Discharge_Current);
            % Calculations for rest iterations
            for i = 2:Number_of_Iterations
                % Following if condition keeps track of which second we are in from the
                % start of that particular day. It is useful in writing generalised
                % charging and discharging conditions later on.
                % b = which second we are in from the start of that particular day
                if(numDays == 1)
                    b = i;
                else
                    b = i - ((numDays - 1)*nSecDay);
                end
                % Since temperature data is generated for only one year, for any
                % Time_of_Study greater than 1 year would result in error. To avoid
                % that a new variable called numDaysTemp is introduced. The follwing
                % function will not work as required in only one case i.e., when
                % numDays is a multiple of 365. Hence, a seperate if condition is
                % specified below.
                numDaysTemp = mod(numDays,365);
                if(numDaysTemp == 0)
                    numDaysTemp = 365;
                end
                T_curr = Cal_T_Curr(Daily_Temp_Min(numDaysTemp),Daily_Temp_Max(numDaysTemp),Norm_Factor,b,cSec); % K - Current temperature
                x_a_curr = Degree_of_Lithiation(SOC_curr,x_SOC_0,x_SOC_100); % Current degree of lithiation
                U_a_curr = OCV_Anode(x_a_curr); % Current OCV of anode
                k_cal_T_SOC_curr = Stress_Factor_Calendar_Aging(T_curr,U_a_curr); % Calendar aging stress factor
                Cal_curr = (k_cal_T_SOC_curr)/(2*sqrt(i/3600)); % Calendar aging integrand
                Q_L_cal_curr = ((Cal_curr + Cal_prev)/2 * (Delta_Time/3600)) + Q_L_cal_prev; % Calendar aging capacity loss (cumulative)
                % Following if condition specifies charging (at 21:00 everyday)
                if((Charge_Start_Time < b) && (Present_Charge <= Present_Total_Charge))
                    Cell_Charge_Throughput = Cell_Charge_Throughput + (Cell_Charge_Current*Delta_Time/3600); % Cumulative
                    Present_Charge = Present_Charge + Cell_Charge_Current*Delta_Time; % C
                    i_Cell_Charge_Throughput = Cell_Charge_Current*Delta_Time/3600; % Ah Discreet (at the moment)
                else
                    i_Cell_Charge_Throughput = 0;
                end
                Cell_Discharge_Throughput = Discharge_Current(b)/(n_cells_parallel*3600); % Ah
                Present_Charge = Present_Charge - (Cell_Discharge_Throughput*3600); % C
                % Total charge throughput refers to amount of charge that has crossed
                % the cell including both charging and discharging whereas charge
                % throughput only refers to only charging scenario
                Cell_Total_Charge_Throughput = Cell_Discharge_Throughput + i_Cell_Charge_Throughput + Cell_Total_Charge_Throughput_prev;
                % Following if condition keeps track of number of days passed since
                % start of study
                if(mod(i,nSecDay) == 0)
                    numDays = numDays + 1;
                end
                % Stress factors for rest three categories
                k_cyc_h_T_curr = Stress_Factor_Cycle_Aging_High_Temperature(T_curr);
                k_cyc_l_T_curr = Stress_Factor_Cycle_Aging_Low_Temperature(T_curr,Cell_Charge_Current);
                k_cyc_l_T_h_SOC_curr = Stress_Factor_Cycle_Aging_Low_Temperature_High_SOC(T_curr,Cell_Charge_Current,SOC_curr);
                % Following if condition makes sure that integrand for high temperature
                % becomes a number only if total charge throughput is not equal to zero
                if Cell_Total_Charge_Throughput ~= 0
                    Cyc_HT_curr = k_cyc_h_T_curr/(2*sqrt(Cell_Total_Charge_Throughput));
                end
                % Following if condition makes sure that integrands for both low
                % temperature categories becomes a number only if charge throughput is
                % not equal to zero
                if Cell_Charge_Throughput ~= 0
                    Cyc_LT_curr = k_cyc_l_T_curr/(2*sqrt(Cell_Charge_Throughput));
                    Cyc_LT_HSOC_curr = k_cyc_l_T_h_SOC_curr;
                end
                % Capacity loss (cumulative) for other three categories is calcuated
                % below
                Q_L_cyc_h_T_curr = ((Cyc_HT_curr + Cyc_HT_prev)*(Cell_Discharge_Throughput+i_Cell_Charge_Throughput)/2) + Q_L_cyc_h_T_prev;
                Q_L_cyc_l_T_curr = ((Cyc_LT_curr + Cyc_LT_prev)*(i_Cell_Charge_Throughput)/2) + Q_L_cyc_l_T_prev;
                Q_L_cyc_LT_HSOC_curr = ((Cyc_LT_HSOC_curr + Cyc_LT_HSOC_prev)*(i_Cell_Charge_Throughput)/2) + Q_L_cyc_LT_HSOC_prev;
                % Following if condition ensures the storage of appropriate variables
                % after every 'Storing_Time' period during Time_of_Study
                if(mod(i,Storing_Time) == 0)
                    app.Time_array(Storing_Index) = i/nSecDay;
                    app.Time_array_Seconds(Storing_Index) = i;
                    app.Q_L_cal(Storing_Index) = 100*Q_L_cal_curr;
                    app.Q_L_cyc_h_T(Storing_Index) = 100*Q_L_cyc_h_T_curr;
                    app.Q_L_cyc_l_T(Storing_Index) = 100*Q_L_cyc_l_T_curr;
                    app.Q_L_cyc_LT_HSOC(Storing_Index) = 100*Q_L_cyc_LT_HSOC_curr;
                    SOC_Plot(Storing_Index) = SOC_curr;
                    app.Q_L_Total(Storing_Index) = app.Q_L_cal(Storing_Index) + app.Q_L_cyc_h_T(Storing_Index) + app.Q_L_cyc_l_T(Storing_Index) + app.Q_L_cyc_LT_HSOC(Storing_Index);
                    Storing_Index = Storing_Index + 1;
                end    
                % SOC will vary due course as capacity loss affects the maximm amount
                % of charge that can be stored by a cell. Since we are douing
                % calculations for each second and the capacity loss is negligible in
                % those time frames, SOC is recalibrated i.e., capacity losses are
                % taken into account after every 'SOC_Recalibration_Time' during the
                % Time_of_Study.
                if(mod(i,SOC_Recalibration_Time) == 0)
                   Present_Total_Charge = Initial_Total_Charge*(1 - Q_L_cal_curr - Q_L_cyc_h_T_curr - Q_L_cyc_l_T_curr - Q_L_cyc_LT_HSOC_curr);
                   SOC_curr = Present_Charge/Present_Total_Charge;
                   % Sometimes SOC becomes greater that 1 due to delay between charing
                   % and recalibration time frames. Hence it is reinitiated to 1 if it
                   % is greater that 1.
                   if SOC_curr > 1
                       SOC_curr = 1;
                   end
                end 
                % Setting previous values to current values so that they can be used in
                % next iteration.
                Cal_prev = Cal_curr; % Integrand of calendar aging
                Q_L_cal_prev = Q_L_cal_curr; % Cumulative capacity loss due to calendar aging
                Cyc_HT_prev = Cyc_HT_curr; % Integrand of cycle aging at high temperature
                Q_L_cyc_h_T_prev = Q_L_cyc_h_T_curr; % Cumulative capacity loss due to cycle aging at high temperature
                Cyc_LT_prev = Cyc_LT_curr; % Integrand of cycle aging at low temperature
                Q_L_cyc_l_T_prev = Q_L_cyc_l_T_curr; % Cumulative capacity loss due to cycle aging at low temperature
                Cyc_LT_HSOC_prev = Cyc_LT_HSOC_curr; % Integrand of cycle aging at high SOC
                Q_L_cyc_LT_HSOC_prev = Q_L_cyc_LT_HSOC_curr; % Cumulative capacity loss due to cycle aging at low temperature and high SOC
                Cell_Total_Charge_Throughput_prev = Cell_Total_Charge_Throughput;
            end
            if Storing_Index <= 1
                app.CapacityFadeEditField.Value = 0;
            else    
                app.CapacityFadeEditField.Value = app.Q_L_Total(Storing_Index - 1);
            end
            % Plotting
            % Temperature
            Daily_Temp_Max_Plot = Daily_Temp_Max - 273.15;
            Daily_Temp_Min_Plot = Daily_Temp_Min - 273.15;
            x_plot = datetime(2022,01,01) + caldays(0:364);
            plot(app.TemperatureDist,x_plot,Daily_Temp_Max_Plot,'r-x','LineWidth',1,'MarkerIndices',Mid_Days);
            hold(app.TemperatureDist,'on');
            plot(app.TemperatureDist,x_plot,Daily_Temp_Min_Plot,'b-o','LineWidth',1,'MarkerIndices',Mid_Days);
            hold(app.TemperatureDist,'off');
            xtickformat(app.TemperatureDist,'MMM');
            switch app.CityDropDown.Value
                case 'Leh'
                    ylim(app.TemperatureDist,[-inf inf]);
                otherwise
                    ylim(app.TemperatureDist,[0 inf]);
            end
            ylabel(app.TemperatureDist,'Temperature (Celsius)');
            legend(app.TemperatureDist,'Monthly Maximum','Monthly Minimum');
            box(app.TemperatureDist,'on');
            % Capacity loss due to calendar aging
            plot(app.CalAgingAxes,app.Time_array,app.Q_L_cal,'bx-','LineWidth',1,'MarkerIndices',1:round(length(app.Q_L_cal)/10):length(app.Q_L_cal))
            % Capacity loss due to cycle aging at high temperature
            plot(app.CycHighTempAxes,app.Time_array,app.Q_L_cyc_h_T,'r+-','LineWidth',1,'MarkerIndices',1:round(length(app.Q_L_cyc_h_T)/10):length(app.Q_L_cyc_h_T))
            % Capacity loss due to cycle aging at low temperature
            plot(app.CycLowTempAxes,app.Time_array,app.Q_L_cyc_l_T,'o-','color',[0 0.5 0],'LineWidth',1,'MarkerIndices',1:round(length(app.Q_L_cyc_l_T)/10):length(app.Q_L_cyc_l_T))
            % Capacity loss due to cycle aging at low temperatue and high SOC
            plot(app.CycLowTempHighSOCAxes,app.Time_array,app.Q_L_cyc_LT_HSOC,'k*-','LineWidth',1,'MarkerIndices',1:round(length(app.Q_L_cyc_LT_HSOC)/10):length(app.Q_L_cyc_LT_HSOC))
            % Total capacity loss
            plot(app.UIAxes,app.Time_array,app.Q_L_Total,'k-','LineWidth',1)
            d.Value = 1;
            d.Message = 'Finishing';
            pause(1) 
            close(d)
            % Functions
            function dtemp = Daily_Temp(Lower_Temp,Upper_Temp,Lower_Day,Upper_Day,i)
                dtemp = Lower_Temp + ((Upper_Temp - Lower_Temp)*(i - Lower_Day)/(Upper_Day - Lower_Day));
            end
            % Vehicle_Discharge_Current - Takes current velocity of vehicle, maximum
            % speed of vehicle and maximum discarge current of vehicle and interpolates
            % between (0,0) and (max_speed,max_dis_curr) to give discharge current at
            % 'velocity'
            function Dis_curr = current(velocity,max_speed,max_dis_curr)
                Dis_curr = max_dis_curr + (max_dis_curr*(velocity-max_speed)/max_speed);
            end
            % Following function calculates temperature at a given second by taking
            % that particular day's maximum and minimum temperatures, normalized day
            % temperatures. b and cSec are needed to select which Norm_Factor to be
            % choosed. b represents which second we are in at that particular day and
            % cSec(i) represents cumulative number of seconds that have passed till i th
            % hour on that particular day. Comparing these two, we get which hour we are
            % on and then select appropriate Norm_Factor
            function T_curr = Cal_T_Curr(T_Min_Day,T_Max_Day,Norm_Factor,b,cSec)
                hour = 1;
                while(hour <= 23)
                    if(b <= cSec(hour))
                        break;
                    else
                        hour = hour + 1;
                    end
                end
                T_curr = T_Min_Day + (Norm_Factor(hour)*(T_Max_Day - T_Min_Day));
            end
            % Degree of lithiation (x_a)
            function x_a = Degree_of_Lithiation(SOC,x_a_SOC_0,x_a_SOC_100)
                x_a = x_a_SOC_0 + (SOC*(x_a_SOC_100 - x_a_SOC_0)); 
            end
            % OCV of anode
            function U_a = OCV_Anode(x_a)
                U_a = 0.6379+(0.5416*exp(-305.5309*x_a))+(0.044*tanh((0.1958-x_a)/0.1088))-(0.1978*tanh((x_a-1.0571)/0.0854))-(0.6875*tanh((x_a+0.0117)/0.0529))-(0.0175*tanh((x_a-0.5692)/0.0875));
            end
            % Stress Factor - Calendar Aging
            function SF_CA = Stress_Factor_Calendar_Aging(Temperature,U_a)
                SF_CA = k_cal_ref*(exp(-E_a_cal*((1/Temperature)-(1/T_ref))/R_g))*(exp(alpha*F*(U_a_ref-U_a)/(R_g*T_ref)) + k0);
            end
            % Stress Factor - Cycle Aging - High temperature
            function SF_CA_HT = Stress_Factor_Cycle_Aging_High_Temperature(Temperature)
                SF_CA_HT = k_cyc_h_T_ref*(exp(-E_a_cyc_h_T*((1/Temperature)-(1/T_ref))/R_g));
            end
            % Stress Factor - Cycle Aging - Low Temperature
            function SF_CA_LT = Stress_Factor_Cycle_Aging_Low_Temperature(Temperature,I_ch)
                SF_CA_LT = k_cyc_l_T_ref*(exp(E_a_cyc_l_T*((1/Temperature)-(1/T_ref))/R_g))*(exp(beta_l_T*(I_ch-I_ch_ref)/C0));
            end
            % Stress Factor - Cycle Aging - Low Temperature - High SOC
            function SF_CA_LT_HS = Stress_Factor_Cycle_Aging_Low_Temperature_High_SOC(Temperature,I_ch,SOC)
                SF_CA_LT_HS = k_cyc_l_T_h_SOC_ref*(exp(E_a_cyc_l_T_h_SOC*((1/Temperature)-(1/T_ref))/R_g))*(exp(beta_l_T_h_SOC*(I_ch-I_ch_ref)/C0))*((1 + sign(SOC-SOC_ref))/2);
            end
        end

        % Value changed function: DetailedResultsCheckBox
        function DetailedResultsCheckBoxValueChanged(app, event)
            % The following if else conditon makes the Detailed Results tab
            % group visible only when check box is ticked. Originally it is
            % set to non-visible
            value = app.DetailedResultsCheckBox.Value;
            if value
                app.DetailedResultsTabGroup.Visible = 'on'; 
            else
                app.DetailedResultsTabGroup.Visible = 'off';
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % This callback function writes and exports (1) Total capacity
            % fade, (2) Calendar aging capacity fade, (3) Cycle aging
            % capacity fade at high temperature, (4) Cycle aging
            % capacity fade at low temperature and (5) Cycle aging
            % capacity fade at low temperature & high SOC into seperate
            % sheets in an excel file.
            tb1 = table(app.Time_array_Seconds',app.Q_L_Total','VariableNames',{'Time (seconds)','Capacity Loss (%)'});
            tb2 = table(app.Time_array_Seconds',app.Q_L_cal','VariableNames',{'Time (seconds)','Capacity Loss - Calendar Aging (%)'});
            tb3 = table(app.Time_array_Seconds',app.Q_L_cyc_h_T','VariableNames',{'Time (seconds)','Capacity Loss - Cycle Aging - High Temperature (%)'});
            tb4 = table(app.Time_array_Seconds',app.Q_L_cyc_l_T','VariableNames',{'Time (seconds)','Capacity Loss - Cycle Aging - Low Temperature (%)'});
            tb5 = table(app.Time_array_Seconds',app.Q_L_cyc_LT_HSOC','VariableNames',{'Time (seconds)','Capacity Loss - Cycle Aging - Low Temperature - High SOC (%)'});
            
            writetable(tb1,'Results.xlsx','Sheet','Total Capacity Loss');
            writetable(tb2,'Results.xlsx','Sheet','Calendar Aging');
            writetable(tb3,'Results.xlsx','Sheet','High Temperature');
            writetable(tb4,'Results.xlsx','Sheet','Low Temperature');
            writetable(tb5,'Results.xlsx','Sheet','Low Temperature High SOC');
        end

        % Selection changed function: VehicleButtonGroup
        function VehicleButtonGroupSelectionChanged(app, event)
            % This callback fuctions makes fields in vehicle tab
            % editable/enabled based on vehicle selected in radi button
            % group. '1' corresponds to Tata Nexon car, '2' corresponds to
            % Ather 450X scooty and '3' corresponds to custom vehicle.
            % Initially Ather and custom are set to non-enabled. If user
            % selects any other radio button, the corresponding fields will
            % be enabled.
            selectedButton = app.VehicleButtonGroup.SelectedObject;
            if selectedButton == app.TataNexonEVPrimeButton
                app.MotorPowerkWEditField.Enable = 'on';
                app.EnergykWhEditField.Enable = 'on';
                app.DischargeVoltageVEditField.Enable = 'on';
                app.MotorPowerkWEditFieldLabel.Enable = 'on';
                app.EnergykWhEditFieldLabel.Enable = 'on';
                app.DischargeVoltageVEditFieldLabel.Enable = 'on';  
                app.MaximumSpeedkmphEditField.Enable = 'on';
                app.MaximumSpeedkmphEditFieldLabel.Enable = 'on';
                app.MotorPowerkWEditField_2.Enable = 'off';
                app.EnergykWhEditField_2.Enable = 'off';
                app.DischargeVoltageVEditField_2.Enable = 'off';
                app.MotorPowerkWEditField_2Label.Enable = 'off';
                app.EnergykWhEditField_2Label.Enable = 'off';
                app.DischargeVoltageVEditField_2Label.Enable = 'off';
                app.MaximumSpeedkmphEditField_2.Enable = 'off';
                app.MaximumSpeedkmphEditField_2Label.Enable = 'off';
                app.MotorPowerkWEditField_3.Enable = 'off';
                app.EnergykWhEditField_3.Enable = 'off';
                app.DischargeVoltageVEditField_3.Enable = 'off';
                app.MotorPowerkWEditField_3.Editable = 'off';
                app.EnergykWhEditField_3.Editable = 'off';
                app.DischargeVoltageVEditField_3.Editable = 'off';
                app.MotorPowerkWEditField_3Label.Enable = 'off';
                app.EnergykWhEditField_3Label.Enable = 'off';
                app.DischargeVoltageVEditField_3Label.Enable = 'off';
                app.MaximumSpeedkmphEditField_3.Enable = 'off';
                app.MaximumSpeedkmphEditField_3Label.Enable = 'off';
                app.MaximumSpeedkmphEditField_3.Editable = 'off';
            elseif selectedButton == app.Ather450XButton
                app.MaximumSpeedkmphEditField.Enable = 'off';
                app.MaximumSpeedkmphEditFieldLabel.Enable = 'off';
                app.MotorPowerkWEditField.Enable = 'off';
                app.EnergykWhEditField.Enable = 'off';
                app.DischargeVoltageVEditField.Enable = 'off';
                app.MotorPowerkWEditFieldLabel.Enable = 'off';
                app.EnergykWhEditFieldLabel.Enable = 'off';
                app.DischargeVoltageVEditFieldLabel.Enable = 'off'; 
                app.MaximumSpeedkmphEditField_2.Enable = 'on';
                app.MaximumSpeedkmphEditField_2Label.Enable = 'on';
                app.MotorPowerkWEditField_2.Enable = 'on';
                app.EnergykWhEditField_2.Enable = 'on';
                app.DischargeVoltageVEditField_2.Enable = 'on';
                app.MotorPowerkWEditField_2Label.Enable = 'on';
                app.EnergykWhEditField_2Label.Enable = 'on';
                app.DischargeVoltageVEditField_2Label.Enable = 'on';
                app.MotorPowerkWEditField_3.Enable = 'off';
                app.EnergykWhEditField_3.Enable = 'off';
                app.DischargeVoltageVEditField_3.Enable = 'off';
                app.MotorPowerkWEditField_3.Editable = 'off';
                app.EnergykWhEditField_3.Editable = 'off';
                app.DischargeVoltageVEditField_3.Editable = 'off';
                app.MotorPowerkWEditField_3Label.Enable = 'off';
                app.EnergykWhEditField_3Label.Enable = 'off';
                app.DischargeVoltageVEditField_3Label.Enable = 'off';
                app.MaximumSpeedkmphEditField_3.Enable = 'off';
                app.MaximumSpeedkmphEditField_3Label.Enable = 'off';
                app.MaximumSpeedkmphEditField_3.Editable = 'off';
            else 
                app.MaximumSpeedkmphEditField.Enable = 'off';
                app.MaximumSpeedkmphEditFieldLabel.Enable = 'off';
                app.MotorPowerkWEditField.Enable = 'off';
                app.EnergykWhEditField.Enable = 'off';
                app.DischargeVoltageVEditField.Enable = 'off';
                app.MotorPowerkWEditFieldLabel.Enable = 'off';
                app.EnergykWhEditFieldLabel.Enable = 'off';
                app.DischargeVoltageVEditFieldLabel.Enable = 'off'; 
                app.MaximumSpeedkmphEditField.Enable = 'off';
                app.MaximumSpeedkmphEditFieldLabel.Enable = 'off';
                app.MotorPowerkWEditField_2.Enable = 'off';
                app.EnergykWhEditField_2.Enable = 'off';
                app.DischargeVoltageVEditField_2.Enable = 'off';
                app.MotorPowerkWEditField_2Label.Enable = 'off';
                app.EnergykWhEditField_2Label.Enable = 'off';
                app.DischargeVoltageVEditField_2Label.Enable = 'off';
                app.MaximumSpeedkmphEditField_2.Enable = 'off';
                app.MaximumSpeedkmphEditField_2Label.Enable = 'off';
                app.MotorPowerkWEditField_3.Enable = 'on';
                app.EnergykWhEditField_3.Enable = 'on';
                app.DischargeVoltageVEditField_3.Enable = 'on';
                app.MotorPowerkWEditField_3.Editable = 'on';
                app.EnergykWhEditField_3.Editable = 'on';
                app.DischargeVoltageVEditField_3.Editable = 'on';
                app.MotorPowerkWEditField_3Label.Enable = 'on';
                app.EnergykWhEditField_3Label.Enable = 'on';
                app.DischargeVoltageVEditField_3Label.Enable = 'on';
                app.MaximumSpeedkmphEditField_3.Enable = 'on';
                app.MaximumSpeedkmphEditField_3Label.Enable = 'on';
                app.MaximumSpeedkmphEditField_3.Editable = 'on';
            end
        end

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            % This callback sets all model parameters
            % editable/non-editable. Initially they are set to
            % non-editable. Only when user selects custom button, they will
            % become editable. As soon as the user selects other buttons,
            % they will become non-editable again.
            selectedButton = app.ModelButtonGroup.SelectedObject;
            if selectedButton == app.CustomModelButton
                app.StoichiometryxSOC100EditField.Editable = 'on';
                app.StoichiometryxSOC0EditField.Editable = 'on';
                app.SOC_RefEditField.Editable = 'on';
                app.beta_LowTHighSOChEditField.Editable = 'on';
                app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Editable = 'on';
                app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Editable = 'on';
                app.beta_LowThEditField.Editable = 'on';
                app.ReferenceCurrentI_ChRefAEditField.Editable = 'on';
                app.NominalCellCapacityC_0AhEditField.Editable = 'on';
                app.ActivationEnergyE_aCycLowTJmolEditField.Editable = 'on';
                app.ReferenceStressFactork_CycLowTRefAh05EditField.Editable = 'on';
                app.ActivationEnergyE_aCycHighTJmolEditField.Editable = 'on';
                app.ReferenceStressFactork_CycHighTRefAh05EditField.Editable = 'on';
                app.FittingParameterk_0EditField.Editable = 'on';
                app.alphaEditField.Editable = 'on';
                app.ReferencePotentialU_aRefVEditField.Editable = 'on';
                app.ActivationEnergyE_aCalJmolEditField.Editable = 'on';
                app.ReferenceStressFactork_CalRefh05EditField.Editable = 'on';
                app.StoichiometryxSOC100EditField.Enable = 'on';
                app.StoichiometryxSOC0EditField.Enable = 'on';
                app.SOC_RefEditField.Enable = 'on';
                app.beta_LowTHighSOChEditField.Enable = 'on';
                app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Enable = 'on';
                app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Enable = 'on';
                app.beta_LowThEditField.Enable = 'on';
                app.ReferenceCurrentI_ChRefAEditField.Enable = 'on';
                app.NominalCellCapacityC_0AhEditField.Enable = 'on';
                app.ActivationEnergyE_aCycLowTJmolEditField.Enable = 'on';
                app.ReferenceStressFactork_CycLowTRefAh05EditField.Enable = 'on';
                app.ActivationEnergyE_aCycHighTJmolEditField.Enable = 'on';
                app.ReferenceStressFactork_CycHighTRefAh05EditField.Enable = 'on';
                app.FittingParameterk_0EditField.Enable = 'on';
                app.alphaEditField.Enable = 'on';
                app.ReferencePotentialU_aRefVEditField.Enable = 'on';
                app.ActivationEnergyE_aCalJmolEditField.Enable = 'on';
                app.ReferenceStressFactork_CalRefh05EditField.Enable = 'on';
            else
                app.StoichiometryxSOC100EditField.Editable = 'off';
                app.StoichiometryxSOC0EditField.Editable = 'off';
                app.SOC_RefEditField.Editable = 'off';
                app.beta_LowTHighSOChEditField.Editable = 'off';
                app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Editable = 'off';
                app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Editable = 'off';
                app.beta_LowThEditField.Editable = 'off';
                app.ReferenceCurrentI_ChRefAEditField.Editable = 'off';
                app.NominalCellCapacityC_0AhEditField.Editable = 'off';
                app.ActivationEnergyE_aCycLowTJmolEditField.Editable = 'off';
                app.ReferenceStressFactork_CycLowTRefAh05EditField.Editable = 'off';
                app.ActivationEnergyE_aCycHighTJmolEditField.Editable = 'off';
                app.ReferenceStressFactork_CycHighTRefAh05EditField.Editable = 'off';
                app.FittingParameterk_0EditField.Editable = 'off';
                app.alphaEditField.Editable = 'off';
                app.ReferencePotentialU_aRefVEditField.Editable = 'off';
                app.ActivationEnergyE_aCalJmolEditField.Editable = 'off';
                app.ReferenceStressFactork_CalRefh05EditField.Editable = 'off';
                app.FittingParameterk_0EditField.Value = 0.142;
                app.alphaEditField.Value = 0.384;
                app.ReferencePotentialU_aRefVEditField.Value = 0.123;
                app.ActivationEnergyE_aCalJmolEditField.Value = 20592;
                app.ReferenceStressFactork_CalRefh05EditField.Value = 0.0003694;
                app.StoichiometryxSOC100EditField.Value = 0.78;
                app.StoichiometryxSOC0EditField.Value = 0.0085;
                app.SOC_RefEditField.Value = 82;
                app.beta_LowTHighSOChEditField.Value = 7.8;
                app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Value = 233000;
                app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Value = 0.000002031;
                app.beta_LowThEditField.Value = 2.64;
                app.ReferenceCurrentI_ChRefAEditField.Value = 3;
                app.NominalCellCapacityC_0AhEditField.Value = 3;
                app.ActivationEnergyE_aCycLowTJmolEditField.Value = 55546;
                app.ReferenceStressFactork_CycLowTRefAh05EditField.Value = 0.0004009;
                app.ActivationEnergyE_aCycHighTJmolEditField.Value = 32699;
                app.ReferenceStressFactork_CycHighTRefAh05EditField.Value = 0.0001456;
                app.StoichiometryxSOC100EditField.Enable = 'off';
                app.StoichiometryxSOC0EditField.Enable = 'off';
                app.SOC_RefEditField.Enable = 'off';
                app.beta_LowTHighSOChEditField.Enable = 'off';
                app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Enable = 'off';
                app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Enable = 'off';
                app.beta_LowThEditField.Enable = 'off';
                app.ReferenceCurrentI_ChRefAEditField.Enable = 'off';
                app.NominalCellCapacityC_0AhEditField.Enable = 'off';
                app.ActivationEnergyE_aCycLowTJmolEditField.Enable = 'off';
                app.ReferenceStressFactork_CycLowTRefAh05EditField.Enable = 'off';
                app.ActivationEnergyE_aCycHighTJmolEditField.Enable = 'off';
                app.ReferenceStressFactork_CycHighTRefAh05EditField.Enable = 'off';
                app.FittingParameterk_0EditField.Enable = 'off';
                app.alphaEditField.Enable = 'off';
                app.ReferencePotentialU_aRefVEditField.Enable = 'off';
                app.ActivationEnergyE_aCalJmolEditField.Enable = 'off';
                app.ReferenceStressFactork_CalRefh05EditField.Enable = 'off';
            end
        end

        % Value changed function: DriveCycleDropDown
        function DriveCycleDropDownValueChanged(app, event)
            % Only when custom opton is selected, browse button gets
            % enabled
            value = app.DriveCycleDropDown.Value;
            switch value
                case 'Custom'
                    app.BrowseFileButton.Enable = 'on';
                otherwise
                    app.BrowseFileButton.Enable = 'off';
            end
        end

        % Value changed function: CityDropDown
        function CityDropDownValueChanged(app, event)
            % Only when custom option is selected, browse button gets
            % enabled
            value = app.CityDropDown.Value;
            switch value
                case 'Custom'
                    app.BrowseFileButton_2.Enable = 'on';
                otherwise
                    app.BrowseFileButton_2.Enable = 'off';
            end            
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            % Lets the user selects excel file
            [app.CustomDriveCycle,path] = uigetfile('*.xlsx');
            app.EditField3.Value = path;
        end

        % Button pushed function: BrowseFileButton_2
        function BrowseFileButton_2Pushed(app, event)
            % Lets the user selects excel file
            [app.CustomTemperature,path2] = uigetfile('*.xlsx');
            app.EditField3_2.Value = path2;
        end

        % Selection changed function: CellButtonGroup
        function CellButtonGroupSelectionChanged(app, event)
            % Only is custom button is selected, fields become editable in
            % cell button group
            selectedButton = app.CellButtonGroup.SelectedObject;
            if selectedButton == app.CustomButton_3
                app.EnergyWhEditField.Enable = 'on';
                app.PowerWEditField.Enable = 'on';
                app.EnergyWhEditField.Editable = 'on';
                app.PowerWEditField.Editable = 'on';
            else
                app.EnergyWhEditField.Value = 9.6;
                app.PowerWEditField.Value = 64;
                app.EnergyWhEditField.Editable = 'off';
                app.PowerWEditField.Editable = 'off';
                app.EnergyWhEditField.Enable = 'off';
                app.PowerWEditField.Enable = 'off';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % This call back funtion sets upper limit and lower limit for
            % simulation days when unit is changed
            value = app.Switch.Value;
            switch value
                case 'Days'
                    if app.SimulationTimeEditField.Value > 2920
                        message = {'Please decrease the simulation time to less than or equal to 2920 days (8 years)'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    elseif app.SimulationTimeEditField.Value < 0
                        message = {'Please give a valid simulation time'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    end
                otherwise
                    if app.SimulationTimeEditField.Value > 8
                        message = {'Please decrease the simulation time to less than or equal to 8 years (2920 days)'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    elseif app.SimulationTimeEditField.Value < 0
                        message = {'Please give a valid simulation time'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    end
            end    
        end

        % Value changed function: SimulationTimeEditField
        function SimulationTimeEditFieldValueChanged(app, event)
            % this callback function sets limit on maximum and minimum number of
            % simulation days. Currently maximum is set to 8 years
            value = app.Switch.Value;
            switch value
                case 'Days'
                    if app.SimulationTimeEditField.Value > 2920
                        message = {'Please decrease the simulation time to less than 2920 days (8 years)'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    elseif app.SimulationTimeEditField.Value < 0
                        message = {'Please give a valid simulation time'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    end
                otherwise
                    if app.SimulationTimeEditField.Value > 8
                        message = {'Please decrease the simulation time to less than 8 years (2920 days)'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    elseif app.SimulationTimeEditField.Value < 0
                        message = {'Please give a valid simulation time'};
                        uialert(app.UIFigure,message,'Error: Invalid simulation time');
                        app.SimulationTimeEditField.Value = 1;
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainTabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.MainTabGroup);
            app.HomeTab.Title = 'Home';
            app.UIAxes = uiaxes(app.HomeTab);
            title(app.UIAxes, 'Capacity Fade vs Time')
            xlabel(app.UIAxes, 'Time (Days)')
            ylabel(app.UIAxes, 'Capacity Loss (%)')
            zlabel(app.UIAxes, 'Z')
            app.CellButtonGroup = uibuttongroup(app.HomeTab);
            app.CellButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CellButtonGroupSelectionChanged, true);
            app.CellButtonGroup.Title = 'Cell';
            app.LFPButton = uiradiobutton(app.CellButtonGroup);
            app.LFPButton.Text = 'LFP';
            app.LFPButton.Value = true;
            app.NMCButton = uiradiobutton(app.CellButtonGroup);
            app.NMCButton.Enable = 'off';
            app.NMCButton.Text = 'NMC';
            app.CustomButton_3 = uiradiobutton(app.CellButtonGroup);
            app.CustomButton_3.Text = 'Custom';
            app.CalculateButton = uibutton(app.HomeTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DetailedResultsCheckBox = uicheckbox(app.HomeTab);
            app.DetailedResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetailedResultsCheckBoxValueChanged, true);
            app.DetailedResultsCheckBox.Text = 'Detailed Results';
            app.ExportDataButton = uibutton(app.HomeTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.TemperatureDistributionPanel = uipanel(app.HomeTab);
            app.TemperatureDistributionPanel.Title = 'Temperature Distribution';
            app.BrowseFileButton_2 = uibutton(app.TemperatureDistributionPanel, 'push');
            app.BrowseFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButton_2Pushed, true);
            app.BrowseFileButton_2.Enable = 'off';
            app.BrowseFileButton_2.Tooltip = {'Select temperature distribution file from local memory'};
            app.BrowseFileButton_2.Text = 'Browse File';
            app.EditField3_2 = uieditfield(app.TemperatureDistributionPanel, 'text');
            app.EditField3_2.Editable = 'off';
            app.EditField3_2.Enable = 'off';
            app.CityDropDownLabel = uilabel(app.TemperatureDistributionPanel);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.TemperatureDistributionPanel);
            app.CityDropDown.Items = {'Chennai', 'Delhi', 'Leh', 'Mumbai', 'Custom'};
            app.CityDropDown.ValueChangedFcn = createCallbackFcn(app, @CityDropDownValueChanged, true);
            app.CityDropDown.Value = 'Chennai';
            app.CapacityFadeEditFieldLabel = uilabel(app.HomeTab);
            app.CapacityFadeEditFieldLabel.Text = 'Capacity Fade (%)';
            app.CapacityFadeEditField = uieditfield(app.HomeTab, 'numeric');
            app.CapacityFadeEditField.Editable = 'off';
            app.DriveCyclePanel = uipanel(app.HomeTab);
            app.DriveCyclePanel.Title = 'Drive Cycle';
            app.DriveCycleDropDownLabel = uilabel(app.DriveCyclePanel);
            app.DriveCycleDropDownLabel.Text = 'Drive Cycle';
            app.DriveCycleDropDown = uidropdown(app.DriveCyclePanel);
            app.DriveCycleDropDown.Items = {'Work Commute', 'Swiggy', 'Ola', 'Custom'};
            app.DriveCycleDropDown.ValueChangedFcn = createCallbackFcn(app, @DriveCycleDropDownValueChanged, true);
            app.DriveCycleDropDown.Value = 'Work Commute';
            app.BrowseFileButton = uibutton(app.DriveCyclePanel, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Enable = 'off';
            app.BrowseFileButton.Tooltip = {'Select drive cycle file from local memory'};
            app.BrowseFileButton.Text = 'Browse File';
            app.EditField3 = uieditfield(app.DriveCyclePanel, 'text');
            app.EditField3.Editable = 'off';
            app.EditField3.Enable = 'off';
            app.CellParametersPanel = uipanel(app.HomeTab);
            app.CellParametersPanel.Title = 'Cell Parameters';
            app.EnergyWhEditFieldLabel = uilabel(app.CellParametersPanel);
            app.EnergyWhEditFieldLabel.Text = 'Energy (Wh)';
            app.EnergyWhEditField = uieditfield(app.CellParametersPanel, 'numeric');
            app.EnergyWhEditField.Editable = 'off';
            app.EnergyWhEditField.Enable = 'off';
            app.EnergyWhEditField.Value = 9.6;
            app.PowerWEditFieldLabel = uilabel(app.CellParametersPanel);
            app.PowerWEditFieldLabel.Text = 'Power (W)';
            app.PowerWEditField = uieditfield(app.CellParametersPanel, 'numeric');
            app.PowerWEditField.Editable = 'off';
            app.PowerWEditField.Enable = 'off';
            app.PowerWEditField.Value = 64;
            app.SimulationTimeEditFieldLabel = uilabel(app.HomeTab);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.HomeTab, 'numeric');
            app.SimulationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeEditFieldValueChanged, true);
            app.SimulationTimeEditField.Value = 1;
            app.Switch = uiswitch(app.HomeTab, 'slider');
            app.Switch.Items = {'Days', 'Years'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Days';
            app.DetailedResultsTabGroup = uitabgroup(app.HomeTab);
            app.DetailedResultsTabGroup.Visible = 'off';
            app.TemperatureDistributionTab = uitab(app.DetailedResultsTabGroup);
            app.TemperatureDistributionTab.Title = 'Temperature Distribution';
            app.TemperatureDist = uiaxes(app.TemperatureDistributionTab);
            title(app.TemperatureDist, 'Maximum and Minimum Temperature Distribution')
            xlabel(app.TemperatureDist, 'Month')
            ylabel(app.TemperatureDist, 'Temperature (Celsius)')
            zlabel(app.TemperatureDist, 'Z')
            app.CalendarAgingTab = uitab(app.DetailedResultsTabGroup);
            app.CalendarAgingTab.Title = 'Calendar Aging';
            app.CalAgingAxes = uiaxes(app.CalendarAgingTab);
            title(app.CalAgingAxes, 'Capacity Loss due to Calendar Aging')
            xlabel(app.CalAgingAxes, 'Time (Days)')
            ylabel(app.CalAgingAxes, 'Capacity Loss (%)')
            zlabel(app.CalAgingAxes, 'Z')
            app.CycleAgingHighTempTab = uitab(app.DetailedResultsTabGroup);
            app.CycleAgingHighTempTab.Title = 'Cycle Aging - High Temp';
            app.CycHighTempAxes = uiaxes(app.CycleAgingHighTempTab);
            title(app.CycHighTempAxes, 'Capacity Loss due to Cycle Aging at High Temperature')
            xlabel(app.CycHighTempAxes, 'Time (Days)')
            ylabel(app.CycHighTempAxes, 'Capacity Loss (%)')
            zlabel(app.CycHighTempAxes, 'Z')
            app.CycleAgingLowTempTab = uitab(app.DetailedResultsTabGroup);
            app.CycleAgingLowTempTab.Title = 'Cycle Aging - Low Temp';
            app.CycLowTempAxes = uiaxes(app.CycleAgingLowTempTab);
            title(app.CycLowTempAxes, 'Capacity Loss due to Cycle Aging at Low Temperature')
            xlabel(app.CycLowTempAxes, 'Time (Days)')
            ylabel(app.CycLowTempAxes, 'Capacity Loss (%)')
            zlabel(app.CycLowTempAxes, 'Z')
            app.CycleAgingLowTempHighSOCTab = uitab(app.DetailedResultsTabGroup);
            app.CycleAgingLowTempHighSOCTab.Title = 'Cycle Aging - Low Temp High SOC';
            app.CycLowTempHighSOCAxes = uiaxes(app.CycleAgingLowTempHighSOCTab);
            title(app.CycLowTempHighSOCAxes, 'Capacity Loss due to Cycle Aging at Low Temperature and High SOC')
            xlabel(app.CycLowTempHighSOCAxes, 'Time (Days)')
            ylabel(app.CycLowTempHighSOCAxes, 'Capacity Loss (%)')
            zlabel(app.CycLowTempHighSOCAxes, 'Z')
            app.VehicleTab = uitab(app.MainTabGroup);
            app.VehicleTab.Title = 'Vehicle';
            app.TataNexonEVPrimePanel = uipanel(app.VehicleTab);
            app.TataNexonEVPrimePanel.Title = 'Tata Nexon EV Prime';
            app.Image = uiimage(app.TataNexonEVPrimePanel);
            app.Image.ImageSource = 'tata_nexon.png';
            app.EnergykWhEditFieldLabel = uilabel(app.TataNexonEVPrimePanel);
            app.EnergykWhEditFieldLabel.Text = 'Energy (kWh)';
            app.EnergykWhEditField = uieditfield(app.TataNexonEVPrimePanel, 'numeric');
            app.EnergykWhEditField.Editable = 'off';
            app.EnergykWhEditField.Value = 30.2;
            app.MotorPowerkWEditFieldLabel = uilabel(app.TataNexonEVPrimePanel);
            app.MotorPowerkWEditFieldLabel.Text = 'Motor Power (kW)';
            app.MotorPowerkWEditField = uieditfield(app.TataNexonEVPrimePanel, 'numeric');
            app.MotorPowerkWEditField.Editable = 'off';
            app.MotorPowerkWEditField.Value = 94.879;
            app.DischargeVoltageVEditFieldLabel = uilabel(app.TataNexonEVPrimePanel);
            app.DischargeVoltageVEditFieldLabel.Text = 'Discharge Voltage (V)';
            app.DischargeVoltageVEditField = uieditfield(app.TataNexonEVPrimePanel, 'numeric');
            app.DischargeVoltageVEditField.Editable = 'off';
            app.DischargeVoltageVEditField.Value = 320;
            app.MaximumSpeedkmphEditFieldLabel = uilabel(app.TataNexonEVPrimePanel);
            app.MaximumSpeedkmphEditFieldLabel.Text = 'Maximum Speed (kmph)';
            app.MaximumSpeedkmphEditField = uieditfield(app.TataNexonEVPrimePanel, 'numeric');
            app.MaximumSpeedkmphEditField.Editable = 'off';
            app.MaximumSpeedkmphEditField.Value = 120;
            app.Ather450XPanel = uipanel(app.VehicleTab);
            app.Ather450XPanel.Title = 'Ather 450X';
            app.Image2 = uiimage(app.Ather450XPanel);
            app.Image2.ImageSource = 'Ather-450-image.jpg';
            app.MaximumSpeedkmphEditField_2Label = uilabel(app.Ather450XPanel);
            app.MaximumSpeedkmphEditField_2Label.Enable = 'off';
            app.MaximumSpeedkmphEditField_2Label.Text = 'Maximum Speed (kmph)';
            app.MaximumSpeedkmphEditField_2 = uieditfield(app.Ather450XPanel, 'numeric');
            app.MaximumSpeedkmphEditField_2.Editable = 'off';
            app.MaximumSpeedkmphEditField_2.Enable = 'off';
            app.MaximumSpeedkmphEditField_2.Value = 80;
            app.MotorPowerkWEditField_2Label = uilabel(app.Ather450XPanel);
            app.MotorPowerkWEditField_2Label.Enable = 'off';
            app.MotorPowerkWEditField_2Label.Text = 'Motor Power (kW)';
            app.MotorPowerkWEditField_2 = uieditfield(app.Ather450XPanel, 'numeric');
            app.MotorPowerkWEditField_2.Editable = 'off';
            app.MotorPowerkWEditField_2.Enable = 'off';
            app.MotorPowerkWEditField_2.Value = 6.2;
            app.EnergykWhEditField_2Label = uilabel(app.Ather450XPanel);
            app.EnergykWhEditField_2Label.Enable = 'off';
            app.EnergykWhEditField_2Label.Text = 'Energy (kWh)';
            app.EnergykWhEditField_2 = uieditfield(app.Ather450XPanel, 'numeric');
            app.EnergykWhEditField_2.Editable = 'off';
            app.EnergykWhEditField_2.Enable = 'off';
            app.EnergykWhEditField_2.Value = 3.24;
            app.DischargeVoltageVEditField_2Label = uilabel(app.Ather450XPanel);
            app.DischargeVoltageVEditField_2Label.Enable = 'off';
            app.DischargeVoltageVEditField_2Label.Text = 'Discharge Voltage (V)';
            app.DischargeVoltageVEditField_2 = uieditfield(app.Ather450XPanel, 'numeric');
            app.DischargeVoltageVEditField_2.Editable = 'off';
            app.DischargeVoltageVEditField_2.Enable = 'off';
            app.DischargeVoltageVEditField_2.Value = 51.1;
            app.CustomVehiclePanel = uipanel(app.VehicleTab);
            app.CustomVehiclePanel.Title = 'Custom Vehicle';
            app.Image3 = uiimage(app.CustomVehiclePanel);
            app.Image3.ImageSource = 'custom_vehicle.png';
            app.MotorPowerkWEditField_3Label = uilabel(app.CustomVehiclePanel);
            app.MotorPowerkWEditField_3Label.Enable = 'off';
            app.MotorPowerkWEditField_3Label.Text = 'Motor Power (kW)';
            app.MotorPowerkWEditField_3 = uieditfield(app.CustomVehiclePanel, 'numeric');
            app.MotorPowerkWEditField_3.Editable = 'off';
            app.MotorPowerkWEditField_3.Enable = 'off';
            app.EnergykWhEditField_3Label = uilabel(app.CustomVehiclePanel);
            app.EnergykWhEditField_3Label.Enable = 'off';
            app.EnergykWhEditField_3Label.Text = 'Energy (kWh)';
            app.EnergykWhEditField_3 = uieditfield(app.CustomVehiclePanel, 'numeric');
            app.EnergykWhEditField_3.Editable = 'off';
            app.EnergykWhEditField_3.Enable = 'off';
            app.DischargeVoltageVEditField_3 = uieditfield(app.CustomVehiclePanel, 'numeric');
            app.DischargeVoltageVEditField_3.Editable = 'off';
            app.DischargeVoltageVEditField_3.Enable = 'off';
            app.DischargeVoltageVEditField_3Label = uilabel(app.CustomVehiclePanel);
            app.DischargeVoltageVEditField_3Label.Enable = 'off';
            app.DischargeVoltageVEditField_3Label.Text = 'Discharge Voltage (V)';
            app.MaximumSpeedkmphEditField_3Label = uilabel(app.CustomVehiclePanel);
            app.MaximumSpeedkmphEditField_3Label.Enable = 'off';
            app.MaximumSpeedkmphEditField_3Label.Text = 'Maximum Speed (kmph)';
            app.MaximumSpeedkmphEditField_3 = uieditfield(app.CustomVehiclePanel, 'numeric');
            app.MaximumSpeedkmphEditField_3.Editable = 'off';
            app.MaximumSpeedkmphEditField_3.Enable = 'off';
            app.VehicleButtonGroup = uibuttongroup(app.VehicleTab);
            app.VehicleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VehicleButtonGroupSelectionChanged, true);
            app.VehicleButtonGroup.Title = 'Vehicle';
            app.TataNexonEVPrimeButton = uiradiobutton(app.VehicleButtonGroup);
            app.TataNexonEVPrimeButton.Text = 'Tata Nexon EV Prime';
            app.TataNexonEVPrimeButton.Value = true;
            app.Ather450XButton = uiradiobutton(app.VehicleButtonGroup);
            app.Ather450XButton.Text = 'Ather 450X';
            app.CustomVehicleButton = uiradiobutton(app.VehicleButtonGroup);
            app.CustomVehicleButton.Text = 'Custom Vehicle';
            app.ModelParametersTab = uitab(app.MainTabGroup);
            app.ModelParametersTab.Title = 'Model Parameters';
            app.CalendarAgingPanel = uipanel(app.ModelParametersTab);
            app.CalendarAgingPanel.Title = 'Calendar Aging';
            app.ReferenceStressFactork_CalRefh05Label = uilabel(app.CalendarAgingPanel);
            app.ReferenceStressFactork_CalRefh05Label.Interpreter = 'latex';
            app.ReferenceStressFactork_CalRefh05Label.Text = '$Reference\;Stress\;Factor\;k_{Cal,Ref}\;(h^{-0.5})$';
            app.ReferenceStressFactork_CalRefh05EditField = uieditfield(app.CalendarAgingPanel, 'numeric');
            app.ReferenceStressFactork_CalRefh05EditField.Editable = 'off';
            app.ReferenceStressFactork_CalRefh05EditField.Enable = 'off';
            app.ReferenceStressFactork_CalRefh05EditField.Value = 0.0003694;
            app.ActivationEnergyE_aCalJmolLabel = uilabel(app.CalendarAgingPanel);
            app.ActivationEnergyE_aCalJmolLabel.Interpreter = 'latex';
            app.ActivationEnergyE_aCalJmolLabel.Text = '$Activation\;Energy\;E_{a,Cal}\;(J/mol)$';
            app.ActivationEnergyE_aCalJmolEditField = uieditfield(app.CalendarAgingPanel, 'numeric');
            app.ActivationEnergyE_aCalJmolEditField.Editable = 'off';
            app.ActivationEnergyE_aCalJmolEditField.Enable = 'off';
            app.ActivationEnergyE_aCalJmolEditField.Value = 20592;
            app.ReferencePotentialU_aRefVLabel = uilabel(app.CalendarAgingPanel);
            app.ReferencePotentialU_aRefVLabel.Interpreter = 'latex';
            app.ReferencePotentialU_aRefVLabel.Text = '$Reference\;Potential\;U_{a,Ref}\;(V)$';
            app.ReferencePotentialU_aRefVEditField = uieditfield(app.CalendarAgingPanel, 'numeric');
            app.ReferencePotentialU_aRefVEditField.Editable = 'off';
            app.ReferencePotentialU_aRefVEditField.Enable = 'off';
            app.ReferencePotentialU_aRefVEditField.Value = 0.123;
            app.alphaLabel = uilabel(app.CalendarAgingPanel);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$\alpha';
            app.alphaEditField = uieditfield(app.CalendarAgingPanel, 'numeric');
            app.alphaEditField.Editable = 'off';
            app.alphaEditField.Enable = 'off';
            app.alphaEditField.Value = 0.384;
            app.FittingParameterk_0Label = uilabel(app.CalendarAgingPanel);
            app.FittingParameterk_0Label.Interpreter = 'latex';
            app.FittingParameterk_0Label.Text = '$Fitting\;Parameter\;k_0$';
            app.FittingParameterk_0EditField = uieditfield(app.CalendarAgingPanel, 'numeric');
            app.FittingParameterk_0EditField.Editable = 'off';
            app.FittingParameterk_0EditField.Enable = 'off';
            app.FittingParameterk_0EditField.Value = 0.142;
            app.CycleAgingatHighTemperaturePanel = uipanel(app.ModelParametersTab);
            app.CycleAgingatHighTemperaturePanel.Title = 'Cycle Aging at High Temperature';
            app.ReferenceStressFactork_CycHighTRefAh05Label = uilabel(app.CycleAgingatHighTemperaturePanel);
            app.ReferenceStressFactork_CycHighTRefAh05Label.Interpreter = 'latex';
            app.ReferenceStressFactork_CycHighTRefAh05Label.Text = '$Reference\;Stress\;Factor\;k_{Cyc,High\;T,Ref}\;(Ah^{-0.5})$';
            app.ReferenceStressFactork_CycHighTRefAh05EditField = uieditfield(app.CycleAgingatHighTemperaturePanel, 'numeric');
            app.ReferenceStressFactork_CycHighTRefAh05EditField.Editable = 'off';
            app.ReferenceStressFactork_CycHighTRefAh05EditField.Enable = 'off';
            app.ReferenceStressFactork_CycHighTRefAh05EditField.Value = 0.0001456;
            app.ActivationEnergyE_aCycHighTJmolLabel = uilabel(app.CycleAgingatHighTemperaturePanel);
            app.ActivationEnergyE_aCycHighTJmolLabel.Interpreter = 'latex';
            app.ActivationEnergyE_aCycHighTJmolLabel.Text = '$Activation\;Energy\;E_{a,Cyc,\;High\;T}\;(J/mol)$';
            app.ActivationEnergyE_aCycHighTJmolEditField = uieditfield(app.CycleAgingatHighTemperaturePanel, 'numeric');
            app.ActivationEnergyE_aCycHighTJmolEditField.Editable = 'off';
            app.ActivationEnergyE_aCycHighTJmolEditField.Enable = 'off';
            app.ActivationEnergyE_aCycHighTJmolEditField.Value = 32699;
            app.CycleAgingatLowTemperaturePanel = uipanel(app.ModelParametersTab);
            app.CycleAgingatLowTemperaturePanel.Title = 'Cycle Aging at Low Temperature';
            app.ReferenceStressFactork_CycLowTRefAh05Label = uilabel(app.CycleAgingatLowTemperaturePanel);
            app.ReferenceStressFactork_CycLowTRefAh05Label.Interpreter = 'latex';
            app.ReferenceStressFactork_CycLowTRefAh05Label.Text = '$Reference\;Stress\;Factor\;k_{Cyc,Low\;T,Ref}\;(Ah^{-0.5})$';
            app.ReferenceStressFactork_CycLowTRefAh05EditField = uieditfield(app.CycleAgingatLowTemperaturePanel, 'numeric');
            app.ReferenceStressFactork_CycLowTRefAh05EditField.Editable = 'off';
            app.ReferenceStressFactork_CycLowTRefAh05EditField.Enable = 'off';
            app.ReferenceStressFactork_CycLowTRefAh05EditField.Value = 0.0004009;
            app.ActivationEnergyE_aCycLowTJmolLabel = uilabel(app.CycleAgingatLowTemperaturePanel);
            app.ActivationEnergyE_aCycLowTJmolLabel.Interpreter = 'latex';
            app.ActivationEnergyE_aCycLowTJmolLabel.Text = '$Activation\;Energy\;E_{a,Cyc,\;Low\;T}\;(J/mol)$';
            app.ActivationEnergyE_aCycLowTJmolEditField = uieditfield(app.CycleAgingatLowTemperaturePanel, 'numeric');
            app.ActivationEnergyE_aCycLowTJmolEditField.Editable = 'off';
            app.ActivationEnergyE_aCycLowTJmolEditField.Enable = 'off';
            app.ActivationEnergyE_aCycLowTJmolEditField.Value = 55546;
            app.NominalCellCapacityC_0AhLabel = uilabel(app.CycleAgingatLowTemperaturePanel);
            app.NominalCellCapacityC_0AhLabel.Interpreter = 'latex';
            app.NominalCellCapacityC_0AhLabel.Text = '$Nominal \;Cell \;Capacity \;C_0 (Ah)$';
            app.NominalCellCapacityC_0AhEditField = uieditfield(app.CycleAgingatLowTemperaturePanel, 'numeric');
            app.NominalCellCapacityC_0AhEditField.Editable = 'off';
            app.NominalCellCapacityC_0AhEditField.Enable = 'off';
            app.NominalCellCapacityC_0AhEditField.Value = 3;
            app.ReferenceCurrentI_ChRefALabel = uilabel(app.CycleAgingatLowTemperaturePanel);
            app.ReferenceCurrentI_ChRefALabel.Interpreter = 'latex';
            app.ReferenceCurrentI_ChRefALabel.Text = '$Reference \;Current \;I_{Ch,Ref} (A)$';
            app.ReferenceCurrentI_ChRefAEditField = uieditfield(app.CycleAgingatLowTemperaturePanel, 'numeric');
            app.ReferenceCurrentI_ChRefAEditField.Editable = 'off';
            app.ReferenceCurrentI_ChRefAEditField.Enable = 'off';
            app.ReferenceCurrentI_ChRefAEditField.Value = 3;
            app.beta_LowThEditFieldLabel = uilabel(app.CycleAgingatLowTemperaturePanel);
            app.beta_LowThEditFieldLabel.Interpreter = 'latex';
            app.beta_LowThEditFieldLabel.Text = '$\beta_{Low \;T} (h)$';
            app.beta_LowThEditField = uieditfield(app.CycleAgingatLowTemperaturePanel, 'numeric');
            app.beta_LowThEditField.Editable = 'off';
            app.beta_LowThEditField.Enable = 'off';
            app.beta_LowThEditField.Value = 2.64;
            app.CycleAgingatLowTemperatureandHighSOCPanel = uipanel(app.ModelParametersTab);
            app.CycleAgingatLowTemperatureandHighSOCPanel.Title = 'Cycle Aging at Low Temperature and High SOC';
            app.ReferenceStressFactork_CycLowTHighSOCRefh1Label = uilabel(app.CycleAgingatLowTemperatureandHighSOCPanel);
            app.ReferenceStressFactork_CycLowTHighSOCRefh1Label.Interpreter = 'latex';
            app.ReferenceStressFactork_CycLowTHighSOCRefh1Label.Text = '$Reference\;Stress\;Factor\;k_{Cyc,Low\;T,High\;SOC,Ref}\;(h^{-1})$';
            app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField = uieditfield(app.CycleAgingatLowTemperatureandHighSOCPanel, 'numeric');
            app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Editable = 'off';
            app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Enable = 'off';
            app.ReferenceStressFactork_CycLowTHighSOCRefh1EditField.Value = 2.031e-06;
            app.ActivationEnergyE_aCycLowTHighSOCJmolLabel = uilabel(app.CycleAgingatLowTemperatureandHighSOCPanel);
            app.ActivationEnergyE_aCycLowTHighSOCJmolLabel.Interpreter = 'latex';
            app.ActivationEnergyE_aCycLowTHighSOCJmolLabel.Text = '$Activation\;Energy\;E_{a,Cyc,\;Low\;T\;High\;SOC}\;(J/mol)$';
            app.ActivationEnergyE_aCycLowTHighSOCJmolEditField = uieditfield(app.CycleAgingatLowTemperatureandHighSOCPanel, 'numeric');
            app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Editable = 'off';
            app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Enable = 'off';
            app.ActivationEnergyE_aCycLowTHighSOCJmolEditField.Value = 233000;
            app.beta_LowTHighSOChEditFieldLabel = uilabel(app.CycleAgingatLowTemperatureandHighSOCPanel);
            app.beta_LowTHighSOChEditFieldLabel.Interpreter = 'latex';
            app.beta_LowTHighSOChEditFieldLabel.Text = '$\beta_{Low\;T\;High\;SOC}\;(h)$';
            app.beta_LowTHighSOChEditField = uieditfield(app.CycleAgingatLowTemperatureandHighSOCPanel, 'numeric');
            app.beta_LowTHighSOChEditField.Editable = 'off';
            app.beta_LowTHighSOChEditField.Enable = 'off';
            app.beta_LowTHighSOChEditField.Value = 7.8;
            app.SOC_RefLabel = uilabel(app.CycleAgingatLowTemperatureandHighSOCPanel);
            app.SOC_RefLabel.Text = 'SOC_Ref (%)';
            app.SOC_RefEditField = uieditfield(app.CycleAgingatLowTemperatureandHighSOCPanel, 'numeric');
            app.SOC_RefEditField.Editable = 'off';
            app.SOC_RefEditField.Enable = 'off';
            app.SOC_RefEditField.Value = 82;
            app.AnodeOpenCircuitPotentialPanel = uipanel(app.ModelParametersTab);
            app.AnodeOpenCircuitPotentialPanel.Title = 'Anode Open Circuit Potential';
            app.StoichiometryxSOC0EditFieldLabel = uilabel(app.AnodeOpenCircuitPotentialPanel);
            app.StoichiometryxSOC0EditFieldLabel.Text = 'Stoichiometry x (SOC = 0%) ';
            app.StoichiometryxSOC0EditField = uieditfield(app.AnodeOpenCircuitPotentialPanel, 'numeric');
            app.StoichiometryxSOC0EditField.Editable = 'off';
            app.StoichiometryxSOC0EditField.Enable = 'off';
            app.StoichiometryxSOC0EditField.Value = 0.0085;
            app.StoichiometryxSOC100EditFieldLabel = uilabel(app.AnodeOpenCircuitPotentialPanel);
            app.StoichiometryxSOC100EditFieldLabel.Text = 'Stoichiometry x (SOC = 100%) ';
            app.StoichiometryxSOC100EditField = uieditfield(app.AnodeOpenCircuitPotentialPanel, 'numeric');
            app.StoichiometryxSOC100EditField.Editable = 'off';
            app.StoichiometryxSOC100EditField.Enable = 'off';
            app.StoichiometryxSOC100EditField.Value = 0.78;
            app.ModelButtonGroup = uibuttongroup(app.ModelParametersTab);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.SchimpeetalButton = uiradiobutton(app.ModelButtonGroup);
            app.SchimpeetalButton.Text = 'Schimpe et al';
            app.SchimpeetalButton.Value = true;
            app.CustomModelButton = uiradiobutton(app.ModelButtonGroup);
            app.CustomModelButton.Text = 'Custom Model';
            app.HelpTab = uitab(app.MainTabGroup);
            app.HelpTab.Title = 'Help';
            app.EditField = uieditfield(app.HelpTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'This app calculates capacity fade in batteries due to calendar aging and cycle aging. Please follow the steps given below:';
            app.Step1EditFieldLabel = uilabel(app.HelpTab);
            app.Step1EditFieldLabel.Text = 'Step 1';
            app.Step1EditField = uieditfield(app.HelpTab, 'text');
            app.Step1EditField.Editable = 'off';
            app.Step1EditField.Value = 'Input simulation time and select units (days or years). Please note that an upper limit of 8 years has been set on the value.';
            app.Step2EditFieldLabel = uilabel(app.HelpTab);
            app.Step2EditFieldLabel.Text = 'Step 2';
            app.Step2EditField = uieditfield(app.HelpTab, 'text');
            app.Step2EditField.Editable = 'off';
            app.Step2EditField.Value = 'Select the type of cell. If it is custom, enter energy (in Wh) and power (in W) in beside boxes. Please note that for LFP, the value cannot be changed.';
            app.Step3EditFieldLabel = uilabel(app.HelpTab);
            app.Step3EditFieldLabel.Text = 'Step 3';
            app.Step3EditField = uieditfield(app.HelpTab, 'text');
            app.Step3EditField.Editable = 'off';
            app.Step3EditField.Value = 'Select drive cycle from drop down menu. If custom is selected, upload a drive cycle in ''.xlsx'' format after clicking browse button.';
            app.Step4EditFieldLabel = uilabel(app.HelpTab);
            app.Step4EditFieldLabel.Text = 'Step 4';
            app.Step4EditField = uieditfield(app.HelpTab, 'text');
            app.Step4EditField.Editable = 'off';
            app.Step4EditField.Value = 'Select city from drop down menu. If custom is selected, upload a temperature distribution in ''.xlsx'' format after clicking browse button.';
            app.Step5EditFieldLabel = uilabel(app.HelpTab);
            app.Step5EditFieldLabel.Text = 'Step 5';
            app.Step5EditField = uieditfield(app.HelpTab, 'text');
            app.Step5EditField.Editable = 'off';
            app.Step5EditField.Value = 'Go to vehicle tab and select the vehicle. If custom is selected, please input the required parameter values.';
            app.Step6EditFieldLabel = uilabel(app.HelpTab);
            app.Step6EditFieldLabel.Text = 'Step 6';
            app.Step6EditField = uieditfield(app.HelpTab, 'text');
            app.Step6EditField.Editable = 'off';
            app.Step6EditField.Value = 'Go to model parameters tab and select the model. If custom is selected, please input the required parameter values.';
            app.Step7EditFieldLabel = uilabel(app.HelpTab);
            app.Step7EditFieldLabel.Text = 'Step 7';
            app.Step7EditField = uieditfield(app.HelpTab, 'text');
            app.Step7EditField.Editable = 'off';
            app.Step7EditField.Value = 'Go to home tab and click calculate button. Once the calculations are over, total capacity fade versus time plot will be plotted in the graph.';
            app.Step8EditFieldLabel = uilabel(app.HelpTab);
            app.Step8EditFieldLabel.Text = 'Step 8';
            app.Step8EditField = uieditfield(app.HelpTab, 'text');
            app.Step8EditField.Editable = 'off';
            app.Step8EditField.Value = 'Check detailed results box to get detailed results tab. Uncheck the same to make the tab diappear.';
            app.Step9EditFieldLabel = uilabel(app.HelpTab);
            app.Step9EditFieldLabel.Text = 'Step 9';
            app.Step9EditField = uieditfield(app.HelpTab, 'text');
            app.Step9EditField.Editable = 'off';
            app.Step9EditField.Value = 'Click export button to export the results into an excel sheet.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Practice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end